classdef morsecode_lastupdate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        MorseCodeLabel                  matlab.ui.control.Label
        mhaviTalyeGLicRtnikLabel        matlab.ui.control.Label
        viTasanCatiBhubeckeTskmCaLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        InternationalMorseCodeTab_2     matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        DecodingandEncodingTab          matlab.ui.container.Tab
        BaudRateDropDown                matlab.ui.control.DropDown
        BaudRateDropDownLabel           matlab.ui.control.Label
        COMPortDropDown                 matlab.ui.control.DropDown
        COMPortDropDownLabel            matlab.ui.control.Label
        ConnectionLamp                  matlab.ui.control.Lamp
        ConnectionLampLabel             matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        SendButton                      matlab.ui.control.Button
        DecodedTextArea                 matlab.ui.control.TextArea
        DecodedTextAreaLabel            matlab.ui.control.Label
        InputTextField                  matlab.ui.control.EditField
        InputTextLabel                  matlab.ui.control.Label
    end


    properties (Access = private)
    a;   
    isConnected;  
  
    end 
    methods (Access = private)        
            function readSerialData(app, src, ~)
            data = readline(src);
            app.DecodedTextArea.Value = [app.DecodedTextArea.Value; data];
            
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        app.isConnected = false;
        app.ConnectionLamp.Color = 'red';
        app.COMPortDropDown();
        app.BaudRateDropDown();

 
        end

        % Drop down opening function: COMPortDropDown
        function COMPortDropDownOpening(app, event)
            ports = serialportlist("available");
            if isempty(ports)
                ports = {'No COM ports available'};
                app.COMPortDropDown.Enable = 'off';
            else
                app.COMPortDropDown.Enable = 'on';
            end
            app.COMPortDropDown.Items = ports;
        
        end

        % Drop down opening function: BaudRateDropDown
        function BaudRateDropDownOpening(app, event)
            baudRates = {'9600', '19200', '38400', '57600', '115200'};
            app.BaudRateDropDown.Items = baudRates;


        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
          
          if ~app.isConnected
               try
               
               selectedPort = app.COMPortDropDown.Value;
               selectedBaudRate = str2double(app.BaudRateDropDown.Value);
               % Connect to Arduino
               app.a = serialport(selectedPort, selectedBaudRate);
               configureCallback(app.a, "terminator", @app.readSerialData);
               app.isConnected = true;
               app.ConnectionLamp.Color = 'green';
               app.ConnectButton.Text = 'Disconnect';
               catch
               uialert(app.UIFigure, 'Failed to connect to Arduino', 'Connection Error');
                end
            else
                % Disconnect from Arduino
                configureCallback(app.a, "off");
                clear app.arduinoObj;
                app.isConnected = false;
                app.ConnectionLamp.Color = 'red';
                app.ConnectButton.Text = 'Connect';
                
               
          end


% Callback function to update the label with received data
configureCallback(app.a, "terminator", @(src, event) updateGUI(src, app));

function updateGUI(src, app)
    % Read the data from the serial port
    data = readline(src);
    
    % Accumulate the received data
    app.DecodedTextArea.Value = strcat(app.DecodedTextArea.Value, data, newline);  % Append new data with a newline
    
    % Display accumulated data in the command window
    disp(['Received Morse Code: ', data]);
end


        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
        if app.isConnected
            inputText = app.InputTextField.Value;
            writeline(app.a, inputText);
       
        end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.InputTextField.Value = '';
            app.DecodedTextArea.Value = '';            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Morse Code';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InternationalMorseCodeTab_2 = uitab(app.TabGroup);
            app.InternationalMorseCodeTab_2.Title = 'International Morse Code';
            app.Image2 = uiimage(app.InternationalMorseCodeTab_2);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '0a52b7593b4adcf4dadf7cc37c399db5.jpg');
            app.DecodingandEncodingTab = uitab(app.TabGroup);
            app.DecodingandEncodingTab.Title = 'Decoding and Encoding';
            app.InputTextLabel = uilabel(app.DecodingandEncodingTab);
            app.InputTextLabel.Text = 'Input Text';
            app.InputTextField = uieditfield(app.DecodingandEncodingTab, 'text');
            app.DecodedTextAreaLabel = uilabel(app.DecodingandEncodingTab);
            app.DecodedTextAreaLabel.Text = 'Decoded Text Area';
            app.DecodedTextArea = uitextarea(app.DecodingandEncodingTab);
            app.SendButton = uibutton(app.DecodingandEncodingTab, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.ClearButton = uibutton(app.DecodingandEncodingTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ConnectButton = uibutton(app.DecodingandEncodingTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect Button';
            app.ConnectionLampLabel = uilabel(app.DecodingandEncodingTab);
            app.ConnectionLampLabel.Text = 'ConnectionLamp';
            app.ConnectionLamp = uilamp(app.DecodingandEncodingTab);
            app.COMPortDropDownLabel = uilabel(app.DecodingandEncodingTab);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.DecodingandEncodingTab);
            app.COMPortDropDown.Items = {'COM', 'Option 2', 'Option 3', 'Option 4'};
            app.COMPortDropDown.DropDownOpeningFcn = createCallbackFcn(app, @COMPortDropDownOpening, true);
            app.COMPortDropDown.Value = 'COM';
            app.BaudRateDropDownLabel = uilabel(app.DecodingandEncodingTab);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.DecodingandEncodingTab);
            app.BaudRateDropDown.Items = {'Baud Rate', 'Option 2', 'Option 3', 'Option 4'};
            app.BaudRateDropDown.DropDownOpeningFcn = createCallbackFcn(app, @BaudRateDropDownOpening, true);
            app.BaudRateDropDown.Value = 'Baud Rate';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.viTasanCatiBhubeckeTskmCaLabel = uilabel(app.UIFigure);
            app.viTasanCatiBhubeckeTskmCaLabel.Text = ' viTüasßanCatiBhubec©keTskm<úCa';
            app.mhaviTalyeGLicRtnikLabel = uilabel(app.UIFigure);
            app.mhaviTalyeGLicRtnikLabel.Text = 'mhaviTüal½yeGLicRtÚnik';
            app.MorseCodeLabel = uilabel(app.UIFigure);
            app.MorseCodeLabel.Text = 'Morse Code ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'npic.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = morsecode_lastupdate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end